classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SliderLabel_4          matlab.ui.control.Label
        HeightSpinner          matlab.ui.control.Spinner
        HeightSpinnerLabel     matlab.ui.control.Label
        LongitudeSpinner       matlab.ui.control.Spinner
        LongitudeSpinnerLabel  matlab.ui.control.Label
        LatitudeSpinner        matlab.ui.control.Spinner
        LatitudeSpinnerLabel   matlab.ui.control.Label
        TextArea_2             matlab.ui.control.TextArea
        Tree                   matlab.ui.container.CheckBoxTree
        GPSNode                matlab.ui.container.TreeNode
        QZSSNode               matlab.ui.container.TreeNode
        BDSNode                matlab.ui.container.TreeNode
        TextArea               matlab.ui.control.TextArea
        GeoplotPanel           matlab.ui.container.Panel
        SliderLabel_3          matlab.ui.control.Label
        SkyplotPanel           matlab.ui.container.Panel
        Slider                 matlab.ui.control.Slider
        PlotSwitch             matlab.ui.control.Switch
        PlotSwitchLabel        matlab.ui.control.Label
        UIAxes2                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PlotSwitch
        function PlotSwitchValueChanged(app, event)
            %% checked box
            allnodes = findall(app.Tree,"Type","uitreenode");
            checked_node = ismember(allnodes,app.Tree.CheckedNodes);
            nav_data = load("nav.mat"); 
            
            GPS = nav_data.nav.GPS;
            QZSS = nav_data.nav.QZSS;
            BDS = nav_data.nav.BDS;
            
            if (checked_node(1) == 1)
                value = app.PlotSwitch.Value;
               
                
                %% set init 
                time = datetime(GPS.toc);
                app.TextArea_2.Value = datestr(time);
                idx = 1;
                ax = geoaxes(app.GeoplotPanel);
                ax_ = polaraxes(app.SkyplotPanel);
                ax__=app.UIAxes2;
                idx_ = 1;
                
    
                if(value=="On")
                    while(1)
                        if(idx>app.Slider.Value)
                            break;
                        end
                        %% true anomoly
                        true_anomoly = getTrueAnomoly(GPS,time);
                        %% PQW2ECI
                        R_PQW2ECI = PQW2ECI(GPS.omega,GPS.i,GPS.OMEGA);
                        %% r at PQW
                        rangeInPQW=solveRangeInPerifocalFrame(GPS.a, GPS.e, true_anomoly);
                        %% r PQW to ECI
                        R_ECI = R_PQW2ECI*rangeInPQW;
                        %% v at PQW
                        velocityInPQW = solveVelocityInPerifocalFrame(GPS.a, GPS.e, true_anomoly);
                        %% ECI to ECEF
                        r_ecef = eci2ecef(time,R_ECI);
                        %% ECEF to ENU
                        % sju chungmugwan rooftop
                        lat0 = app.LatitudeSpinner.Value;% [rad]
                        lon0 = app.LongitudeSpinner.Value; % [rad]
                        h0 = app.HeightSpinner.Value ; %[m]
                        wgs84 = wgs84Ellipsoid('meter');
                        [xEast,yNorth,zUp] = ecef2enu(r_ecef(1),r_ecef(2),r_ecef(3),lat0,lon0,h0,wgs84);
                        %% ECI to ENU DCM
                        % DCM ecef2enu
                        R_ecef2enu = [1 0 0;...
                                      0 cosd(90-lat0) sind(90-lat0);...
                                      0 -sind(90-lat0) cosd(90-lat0)]*[cosd(90+lon0) sind(90+lon0) 0;...
                                                                        -sind(90+lon0) cosd(90+lon0) 0;...
                                                                        0 0 1];
                        R_eci2ecef = dcmeci2ecef('IAU-2000/2006',time);
                        R_eci2enu = R_ecef2enu*R_eci2ecef;
                        [Vel_E,Vel_N,Vel_U] = VEl_ENU(R_eci2enu,R_PQW2ECI,velocityInPQW,R_ECI);
                        %% ECEF tto geodetic
                        lla = ecef2lla(r_ecef');
                        %% ENU to azimuth & elevation
                        az = azimuth([xEast,yNorth,zUp]);
                        el = elevation_([xEast,yNorth,zUp], 0);
                        
                        %% Radar Measurement
                        if (isnan(el)~=1)
                            R_radar = norm([xEast,yNorth,zUp]);
                            [l__,az__,el__,l__dot,az__dot,el__dot] = Radar(R_radar,az,el,R_eci2enu,R_PQW2ECI,velocityInPQW,R_ECI);
                            Radar_measurement = [l__,az__,el__,l__dot,az__dot,el__dot];
                        end

                        if (isnan(el)==1)
                            Radar_measurement(1,1:6) = "NaN";
                        end

                        %% Visualization
                        drawnow;
                        % true anomaly & ECI position
                        scatter3(ax__,R_ECI(1),R_ECI(2),R_ECI(3),'k','filled');
                        hold( ax__, 'on' );
                        scatter3(ax__,0,0,0,1000,'blue','filled');
                        
                        % Ground Track
                        lat(idx) = lla(1);
                        lon(idx) = lla(2);
                        geoscatter(ax,lat,lon,'k','filled');
                        hold( ax, 'on' );
                        
                        
                        % sky plot
                        if(zUp>0)   
                            GPS_az(idx_) = az*pi/180;
                            GPS_el(idx_) = el*pi/180;
                            % using unit sphere
                            polarscatter(ax_,GPS_az,cos(GPS_el),'k','filled');
                            ax_.ThetaZeroLocation = 'top';
                            ax_.ThetaDir = 'clockwise';
                            idx_ = idx_ + 1;
                        end
                        %% text term 
                        text_time = "Date : " + convertCharsToStrings(datestr(time)) + newline;
                        text_Velocity_PQW_P = num2str(velocityInPQW(1)) + " [m/s]"+newline;
                        text_Velocity_PQW_Q = num2str(velocityInPQW(2)) + " [m/s] "+newline;
                        text_Velocity_ENU_E = "Relative ENU Vel_E : " + num2str(Vel_E) + "[m/s]"+newline;
                        text_Velocity_ENU_N = "Relative ENU Vel_N : " + num2str(Vel_N) + "[m/s]"+newline;
                        text_Velocity_ENU_U = "Relative ENU Vel_U : " + num2str(Vel_U) + "[m/s]"+newline;
                        text_radar = newline+"________Radar Measurement________ "+ newline ...
                            + newline + "Range : " + num2str(Radar_measurement(1)) + " [m] " + newline ...
                            + "Azimuth : " + num2str(Radar_measurement(2)) + " [deg] " + newline ...
                            + "Elevation :" + num2str(Radar_measurement(3)) + " [deg] " + newline ...
                            + "Range derivative Time : " + num2str(Radar_measurement(4)) + " [m/s] " + newline ...
                            + "Azimuth derivative Time : " + num2str(Radar_measurement(5)) + " [deg/s] " + newline ...
                            + "Elevation derivative Time : " + num2str(Radar_measurement(6)) + " [deg/s] ";
                        text_ = text_time + newline + "PQW Vel_P : "+text_Velocity_PQW_P + newline+ "PQW Vel_Q: "+text_Velocity_PQW_Q+...
                            newline+text_Velocity_ENU_E+newline+text_Velocity_ENU_N+newline+text_Velocity_ENU_U+text_radar;
                        
                        app.TextArea.Value = text_;
                        %% update time,idx
                        time = time + hours;
                        idx = idx + 1 ;
                    end
                end
            end

            if (checked_node(2) == 1)
                value = app.PlotSwitch.Value;
               
                
                %% set init 
                time = datetime(QZSS.toc);
                app.TextArea_2.Value = datestr(time);
                idx = 1;
                ax = geoaxes(app.GeoplotPanel);
                ax_ = polaraxes(app.SkyplotPanel);
                ax__=app.UIAxes2;
                idx_ = 1;
                
    
                if(value=="On")
                    while(1)
                        if(idx>app.Slider.Value)
                            break;
                        end
                        %% true anomoly
                        true_anomoly = getTrueAnomoly(QZSS,time);
                        %% PQW2ECI
                        R_PQW2ECI = PQW2ECI(QZSS.omega,QZSS.i,QZSS.OMEGA);
                        %% r at PQW
                        rangeInPQW=solveRangeInPerifocalFrame(QZSS.a, QZSS.e, true_anomoly);
                        %% r PQW to ECI
                        R_ECI = R_PQW2ECI*rangeInPQW;
                        %% v at PQW
                        velocityInPQW = solveVelocityInPerifocalFrame(QZSS.a, QZSS.e, true_anomoly);
                        %% ECI to ECEF
                        r_ecef = eci2ecef(time,R_ECI);
                        %% ECEF to ENU
                        % sju chungmugwan rooftop
                        lat0 = app.LatitudeSpinner.Value;% [rad]
                        lon0 = app.LongitudeSpinner.Value; % [rad]
                        h0 = app.HeightSpinner.Value ; %[m]
                        wgs84 = wgs84Ellipsoid('meter');
                        [xEast,yNorth,zUp] = ecef2enu(r_ecef(1),r_ecef(2),r_ecef(3),lat0,lon0,h0,wgs84);
                        %% ECI to ENU DCM
                        % DCM ecef2enu
                        R_ecef2enu = [1 0 0;...
                                      0 cosd(90-lat0) sind(90-lat0);...
                                      0 -sind(90-lat0) cosd(90-lat0)]*[cosd(90+lon0) sind(90+lon0) 0;...
                                                                        -sind(90+lon0) cosd(90+lon0) 0;...
                                                                        0 0 1];
                        R_eci2ecef = dcmeci2ecef('IAU-2000/2006',time);
                        R_eci2enu = R_ecef2enu*R_eci2ecef;
                        [Vel_E,Vel_N,Vel_U] = VEl_ENU(R_eci2enu,R_PQW2ECI,velocityInPQW,R_ECI);
                        %% ECEF tto geodetic
                        lla = ecef2lla(r_ecef');
                        %% ENU to azimuth & elevation
                        az = azimuth([xEast,yNorth,zUp]);
                        el = elevation_([xEast,yNorth,zUp], 0);
                        %% Radar Measurement
                        if (isnan(el)~=1)
                            R_radar = norm([xEast,yNorth,zUp]);
                            [l__,az__,el__,l__dot,az__dot,el__dot] = Radar(R_radar,az,el,R_eci2enu,R_PQW2ECI,velocityInPQW,R_ECI);
                            Radar_measurement = [l__,az__,el__,l__dot,az__dot,el__dot];
                        end

                        if (isnan(el)==1)
                            Radar_measurement(1,1:6) = "NaN";
                        end
                        %% Visualization
                        drawnow;
                        % true anomaly & ECI position
                        scatter3(ax__,R_ECI(1),R_ECI(2),R_ECI(3),'k','filled');
                        hold( ax__, 'on' );
                        scatter3(ax__,0,0,0,1000,'blue','filled');
                        
                        % Ground Track
                        lat(idx) = lla(1);
                        lon(idx) = lla(2);
                        geoscatter(ax,lat,lon,'k','filled');
                        hold( ax, 'on' );
                        
                        
                        % sky plot
                        if(zUp>0)   
                            GPS_az(idx_) = az*pi/180;
                            GPS_el(idx_) = el*pi/180;
                            % using unit sphere
                            polarscatter(ax_,GPS_az,cos(GPS_el),'k','filled');
                            ax_.ThetaZeroLocation = 'top';
                            ax_.ThetaDir = 'clockwise';
                            idx_ = idx_ + 1;
                        end
                       %% text term 
                        text_time = "Date : " + convertCharsToStrings(datestr(time)) + newline;
                        text_Velocity_PQW_P = num2str(velocityInPQW(1)) + " [m/s]"+newline;
                        text_Velocity_PQW_Q = num2str(velocityInPQW(2)) + " [m/s] "+newline;
                        text_Velocity_ENU_E = "Relative ENU Vel_E : " + num2str(Vel_E) + "[m/s]"+newline;
                        text_Velocity_ENU_N = "Relative ENU Vel_N : " + num2str(Vel_N) + "[m/s]"+newline;
                        text_Velocity_ENU_U = "Relative ENU Vel_U : " + num2str(Vel_U) + "[m/s]"+newline;
                        text_radar = newline+"________Radar Measurement________ "+ newline ...
                            + newline + "Range : " + num2str(Radar_measurement(1)) + " [m] " + newline ...
                            + "Azimuth : " + num2str(Radar_measurement(2)) + " [deg] " + newline ...
                            + "Elevation :" + num2str(Radar_measurement(3)) + " [deg] " + newline ...
                            + "Range derivative Time : " + num2str(Radar_measurement(4)) + " [m/s] " + newline ...
                            + "Azimuth derivative Time : " + num2str(Radar_measurement(5)) + " [deg/s] " + newline ...
                            + "Elevation derivative Time : " + num2str(Radar_measurement(6)) + " [deg/s] ";
                        text_ = text_time + newline + "PQW Vel_P : "+text_Velocity_PQW_P + newline+ "PQW Vel_Q: "+text_Velocity_PQW_Q+...
                            newline+text_Velocity_ENU_E+newline+text_Velocity_ENU_N+newline+text_Velocity_ENU_U+text_radar;
                        
                        app.TextArea.Value = text_;
                        %% update time,idx
                        time = time + hours;
                        idx = idx + 1 ;
                    end
                end
            end

            if (checked_node(3) == 1)
                value = app.PlotSwitch.Value;
               
                
                %% set init 
                time = datetime(BDS.toc);
                app.TextArea_2.Value = datestr(time);
                idx = 1;
                ax = geoaxes(app.GeoplotPanel);
                ax_ = polaraxes(app.SkyplotPanel);
                ax__=app.UIAxes2;
                idx_ = 1;
                
    
                if(value=="On")
                    while(1)
                        if(idx>app.Slider.Value)
                            break;
                        end
                        %% true anomoly
                        true_anomoly = getTrueAnomoly(BDS,time);
                        %% PQW2ECI
                        R_PQW2ECI = PQW2ECI(BDS.omega,BDS.i,BDS.OMEGA);
                        %% r at PQW
                        rangeInPQW=solveRangeInPerifocalFrame(BDS.a, BDS.e, true_anomoly);
                        %% r PQW to ECI
                        R_ECI = R_PQW2ECI*rangeInPQW;
                        %% v at PQW
                        velocityInPQW = solveVelocityInPerifocalFrame(BDS.a, BDS.e, true_anomoly);
                        %% ECI to ECEF
                        r_ecef = eci2ecef(time,R_ECI);
                        %% ECEF to ENU
                        % sju chungmugwan rooftop
                        lat0 = app.LatitudeSpinner.Value;% [rad]
                        lon0 = app.LongitudeSpinner.Value; % [rad]
                        h0 = app.HeightSpinner.Value ; %[m]
                        wgs84 = wgs84Ellipsoid('meter');
                        [xEast,yNorth,zUp] = ecef2enu(r_ecef(1),r_ecef(2),r_ecef(3),lat0,lon0,h0,wgs84);
                        %% ECI to ENU DCM
                        % DCM ecef2enu
                        R_ecef2enu = [1 0 0;...
                                      0 cosd(90-lat0) sind(90-lat0);...
                                      0 -sind(90-lat0) cosd(90-lat0)]*[cosd(90+lon0) sind(90+lon0) 0;...
                                                                        -sind(90+lon0) cosd(90+lon0) 0;...
                                                                        0 0 1];
                        R_eci2ecef = dcmeci2ecef('IAU-2000/2006',time);
                        R_eci2enu = R_ecef2enu*R_eci2ecef;
                        [Vel_E,Vel_N,Vel_U] = VEl_ENU(R_eci2enu,R_PQW2ECI,velocityInPQW,R_ECI);
                        %% ECEF tto geodetic
                        lla = ecef2lla(r_ecef');
                        %% ENU to azimuth & elevation
                        az = azimuth([xEast,yNorth,zUp]);
                        el = elevation_([xEast,yNorth,zUp], 0);
                        %% Radar Measurement
                        if (isnan(el)~=1)
                            R_radar = norm([xEast,yNorth,zUp]);
                            [l__,az__,el__,l__dot,az__dot,el__dot] = Radar(R_radar,az,el,R_eci2enu,R_PQW2ECI,velocityInPQW,R_ECI);
                            Radar_measurement = [l__,az__,el__,l__dot,az__dot,el__dot];
                        end

                        if (isnan(el)==1)
                            Radar_measurement(1,1:6) = "NaN";
                        end
                        %% Visualization
                        drawnow;
                        % true anomaly & ECI position
                        scatter3(ax__,R_ECI(1),R_ECI(2),R_ECI(3),'k','filled');
                        hold( ax__, 'on' );
                        scatter3(ax__,0,0,0,1000,'blue','filled');
                        
                        % Ground Track
                        lat(idx) = lla(1);
                        lon(idx) = lla(2);
                        geoscatter(ax,lat,lon,'k','filled');
                        hold( ax, 'on' );
                        
                        
                        % sky plot
                        if(zUp>0)   
                            GPS_az(idx_) = az*pi/180;
                            GPS_el(idx_) = el*pi/180;
                            % using unit sphere
                            polarscatter(ax_,GPS_az,cos(GPS_el),'k','filled');
                            ax_.ThetaZeroLocation = 'top';
                            ax_.ThetaDir = 'clockwise';
                            idx_ = idx_ + 1;
                        end
                      %% text term 
                        text_time = "Date : " + convertCharsToStrings(datestr(time)) + newline;
                        text_Velocity_PQW_P = num2str(velocityInPQW(1)) + " [m/s]"+newline;
                        text_Velocity_PQW_Q = num2str(velocityInPQW(2)) + " [m/s] "+newline;
                        text_Velocity_ENU_E = "Relative ENU Vel_E : " + num2str(Vel_E) + "[m/s]"+newline;
                        text_Velocity_ENU_N = "Relative ENU Vel_N : " + num2str(Vel_N) + "[m/s]"+newline;
                        text_Velocity_ENU_U = "Relative ENU Vel_U : " + num2str(Vel_U) + "[m/s]"+newline;
                        text_radar = newline+"________Radar Measurement________ "+ newline ...
                            + newline + "Range : " + num2str(Radar_measurement(1)) + " [m] " + newline ...
                            + "Azimuth : " + num2str(Radar_measurement(2)) + " [deg] " + newline ...
                            + "Elevation :" + num2str(Radar_measurement(3)) + " [deg] " + newline ...
                            + "Range derivative Time : " + num2str(Radar_measurement(4)) + " [m/s] " + newline ...
                            + "Azimuth derivative Time : " + num2str(Radar_measurement(5)) + " [deg/s] " + newline ...
                            + "Elevation derivative Time : " + num2str(Radar_measurement(6)) + " [deg/s] ";
                        text_ = text_time + newline + "PQW Vel_P : "+text_Velocity_PQW_P + newline+ "PQW Vel_Q: "+text_Velocity_PQW_Q+...
                            newline+text_Velocity_ENU_E+newline+text_Velocity_ENU_N+newline+text_Velocity_ENU_U+text_radar;
                        
                        app.TextArea.Value = text_;
                        %% update time,idx
                        time = time + hours;
                        idx = idx + 1 ;
                    end
                end
            end
        end

        % Callback function
        function PlotSwitch_2ValueChanged(app, event)
            value = app.PlotSwitch.Value;
            nav_data = load("nav.mat"); 
            GPS = nav_data.nav.GPS;
            QZSS = nav_data.nav.QZSS;
            BDS = nav_data.nav.BDS;
            %% set init 
            time = datetime(GPS.toc);
            idx = 1;
            %% 
            if(value=="On")
                while(1)
                    if(idx>app.Slider.Value)
                        break;
                    end
                    %% true anomoly
                    true_anomoly = getTrueAnomoly(GPS,time);
                    %% PQW2ECI
                    R_PQW2ECI = PQW2ECI(GPS.omega,GPS.i,GPS.OMEGA);
                    %% r at PQW
                    rangeInPQW=solveRangeInPerifocalFrame(GPS.a, GPS.e, true_anomoly);
                    %% r PQW to ECI
                    R_ECI = R_PQW2ECI*rangeInPQW;
                    %% v at PQW
                    velocityInPQW = solveVelocityInPerifocalFrame(GPS.a, GPS.e, true_anomoly);
                    %% ECI to ECEF
                    r_ecef = eci2ecef(time,R_ECI);
                    %% ECEF to ENU
                    % sju chungmugwan rooftop
                    lat0 = 37.552289;% [rad]
                    lon0 = 127.073979; % [rad]
                    h0 = 40 ; %[m]
                    wgs84 = wgs84Ellipsoid('meter');
                    [xEast,yNorth,zUp] = ecef2enu(r_ecef(1),r_ecef(2),r_ecef(3),lat0,lon0,h0,wgs84);
                    %% ECEF tto geodetic
                    lla = ecef2lla(r_ecef');
                    %% ENU to azimuth & elevation
                    az = azimuth([xEast,yNorth,zUp]);
                    el = elevation_([xEast,yNorth,zUp], 0);
                    %% Visualization
                    
                    % true anomaly & ECI position
                    scatter3(app.UIAxes2,R_ECI(1),R_ECI(2),R_ECI(3),'k');
                    hold( app.UIAxes2, 'on' );
                    drawnow;
                    %% update time,idx
                    time = time + hours;
                    idx = idx + 1 ;
                end
            end
        end

        % Callback function
        function PlotSwitch_3ValueChanged(app, event)
            value = app.PlotSwitch.Value;
            nav_data = load("nav.mat"); 
            GPS = nav_data.nav.GPS;
            QZSS = nav_data.nav.QZSS;
            BDS = nav_data.nav.BDS;
            %% set init 
            time = datetime(GPS.toc);
            idx = 1;
            idx_ = 1;
            ax = polaraxes(app.SkyplotPanel);
            %% 
            if(value=="On")
                while(1)
                    if(idx>app.Slider.Value)
                        break;
                    end
                    %% true anomoly
                    true_anomoly = getTrueAnomoly(GPS,time);
                    %% PQW2ECI
                    R_PQW2ECI = PQW2ECI(GPS.omega,GPS.i,GPS.OMEGA);
                    %% r at PQW
                    rangeInPQW=solveRangeInPerifocalFrame(GPS.a, GPS.e, true_anomoly);
                    %% r PQW to ECI
                    R_ECI = R_PQW2ECI*rangeInPQW;
                    %% v at PQW
                    velocityInPQW = solveVelocityInPerifocalFrame(GPS.a, GPS.e, true_anomoly);
                    %% ECI to ECEF
                    r_ecef = eci2ecef(time,R_ECI);
                    %% ECEF to ENU
                    % sju chungmugwan rooftop
                    lat0 = 37.552289;% [rad]
                    lon0 = 127.073979; % [rad]
                    h0 = 40 ; %[m]
                    wgs84 = wgs84Ellipsoid('meter');
                    [xEast,yNorth,zUp] = ecef2enu(r_ecef(1),r_ecef(2),r_ecef(3),lat0,lon0,h0,wgs84);
                    
                    %% ECEF tto geodetic
                    lla = ecef2lla(r_ecef');
                    %% ENU to azimuth & elevation
                    az = azimuth([xEast,yNorth,zUp]);
                    el = elevation_([xEast,yNorth,zUp], 0);
                    
                    if(zUp>0)
                        %% Visualization
                        % sky plot
                        
                        GPS_az(idx_) = az*pi/180;
                        GPS_el(idx_) = el*pi/180;
                        % using unit sphere
                        polarscatter(ax,GPS_az,cos(GPS_el),'k','filled');
                        ax.ThetaZeroLocation = 'top';
                        ax.ThetaDir = 'clockwise';
                        idx_ = idx_ + 1;
                    end
                    
                    drawnow
                    %% update time,idx
                    time = time + hours;
                    idx = idx + 1 ;
                end
            end
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'ECI')
            xlabel(app.UIAxes2, 'X ECI[m]')
            ylabel(app.UIAxes2, 'Y ECI[m]')
            zlabel(app.UIAxes2, 'Z ECI[m]')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.PlotSwitchLabel = uilabel(app.UIFigure);
            app.PlotSwitchLabel.Text = 'Plot';
            app.PlotSwitch = uiswitch(app.UIFigure, 'slider');
            app.PlotSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotSwitchValueChanged, true);
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 72];
            app.Slider.Value = 30;
            app.SkyplotPanel = uipanel(app.UIFigure);
            app.SkyplotPanel.Title = 'Skyplot';
            app.SliderLabel_3 = uilabel(app.UIFigure);
            app.SliderLabel_3.Text = 'Hour';
            app.GeoplotPanel = uipanel(app.UIFigure);
            app.GeoplotPanel.Title = 'Geoplot';
            app.TextArea = uitextarea(app.UIFigure);
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.GPSNode = uitreenode(app.Tree);
            app.GPSNode.Text = 'GPS';
            app.QZSSNode = uitreenode(app.Tree);
            app.QZSSNode.Text = 'QZSS';
            app.BDSNode = uitreenode(app.Tree);
            app.BDSNode.Text = 'BDS';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.LatitudeSpinnerLabel = uilabel(app.UIFigure);
            app.LatitudeSpinnerLabel.Text = 'Latitude';
            app.LatitudeSpinner = uispinner(app.UIFigure);
            app.LatitudeSpinner.Value = 37.552289;
            app.LongitudeSpinnerLabel = uilabel(app.UIFigure);
            app.LongitudeSpinnerLabel.Text = 'Longitude';
            app.LongitudeSpinner = uispinner(app.UIFigure);
            app.LongitudeSpinner.Value = 127.073979;
            app.HeightSpinnerLabel = uilabel(app.UIFigure);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.UIFigure);
            app.HeightSpinner.Value = 40;
            app.SliderLabel_4 = uilabel(app.UIFigure);
            app.SliderLabel_4.Text = 'Default is SJU chungmugwan rooftop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end